classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GRADEONEUIFigure             matlab.ui.Figure
        ElaboradoporOLIVANJUANDEDIOSROJASLabel  matlab.ui.control.Label
        Label2                       matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        GRAFICASDEUNSISTEMADEUNGRADODELIBERTADLabel  matlab.ui.control.Label
        RESULTADOSPanel              matlab.ui.container.Panel
        A2EditField                  matlab.ui.control.NumericEditField
        A2EditFieldLabel             matlab.ui.control.Label
        A1EditField                  matlab.ui.control.NumericEditField
        A1Label                      matlab.ui.control.Label
        TnEditField                  matlab.ui.control.NumericEditField
        TnEditFieldLabel             matlab.ui.control.Label
        nEditField                   matlab.ui.control.NumericEditField
        nLabel                       matlab.ui.control.Label
        CEditField                   matlab.ui.control.NumericEditField
        CLabel                       matlab.ui.control.Label
        INGRESODEDATOSPanel          matlab.ui.container.Panel
        AmortEdit                    matlab.ui.control.NumericEditField
        AmortSlider                  matlab.ui.control.Slider
        DEAMORTCRITICOLabel          matlab.ui.control.Label
        VelocLSlider                 matlab.ui.control.Slider
        VELOCIDADINICIALSliderLabel  matlab.ui.control.Label
        PosiSlider                   matlab.ui.control.Slider
        POSICIONINICIALSliderLabel   matlab.ui.control.Label
        RigidezSlider                matlab.ui.control.Slider
        RIGIDEZKSliderLabel          matlab.ui.control.Label
        VelocEdit                    matlab.ui.control.NumericEditField
        PosiEdit                     matlab.ui.control.NumericEditField
        RigidezEdit                  matlab.ui.control.NumericEditField
        MasaSlider                   matlab.ui.control.Slider
        MASAMSliderLabel             matlab.ui.control.Label
        MasaEdit                     matlab.ui.control.NumericEditField
        Grafica6                     matlab.ui.control.UIAxes
        Grafica5                     matlab.ui.control.UIAxes
        Grafica4                     matlab.ui.control.UIAxes
        Grafica3                     matlab.ui.control.UIAxes
        Grafica2                     matlab.ui.control.UIAxes
        Grafica1                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MasaEdit
        function MasaEditValueChanged(app, event)
            
            value = app.MasaEdit.Value;
            app.MasaSlider.Value = value ;
            
            cla(app.Grafica1,'reset')
            cla(app.Grafica2,'reset')
            cla(app.Grafica3,'reset')
            cla(app.Grafica4,'reset')
            cla(app.Grafica5,'reset')
            cla(app.Grafica6,'reset')
           
            
             %CALCULAMOS Wn
            app.nEditField.Value = sqrt(app.RigidezEdit.Value / app.MasaEdit.Value) ;
            
            %CALCULAMOS Tn
             app.TnEditField.Value = (2*pi)/app.nEditField.Value ;
             
            % GRAFICA N°1 AMORTIGUAMIENTO - OSCILACION VS TIEMPO
             hold(app.Grafica1,"on");
           
                         title(app.Grafica1, 'AMORTIGUAMIENTO - OSCILACION VS TIEMPO')
            xlabel(app.Grafica1, 'tiempo (t)')
            ylabel(app.Grafica1, '')
            app.Grafica1.FontSize = 8;
            title(app.Grafica2, 'POSICION - TIEMPO')
            xlabel(app.Grafica2, 'tiempo (t)')
            ylabel(app.Grafica2, 'u(t)-(m)')
            app.Grafica2.FontSize = 8;

            title(app.Grafica3, 'VELOCIDAD - TIEMPO')
            xlabel(app.Grafica3, 'tiempo (t)')
            ylabel(app.Grafica3, 'ů(t) - (m/s)')
            app.Grafica3.FontSize = 8;

            title(app.Grafica4, 'FUERZA RESTITUTIVA - TIEMPO')
            xlabel(app.Grafica4, 'tiempo (t)')
            ylabel(app.Grafica4, 'Fr(N)')
            app.Grafica4.FontSize = 8;

            title(app.Grafica5, 'FUERZA DE AMORTIGUAMIENTO - TIEMPO')
            xlabel(app.Grafica5, 'tiempo (t)')
            ylabel(app.Grafica5, 'Fd(N)')
            app.Grafica5.FontSize = 8;

            title(app.Grafica6, 'FUERZA DE ACELERACIÓN - TIEMPO')
            xlabel(app.Grafica6, 'tiempo (t)')
            ylabel(app.Grafica6, 'Fr(N)')
            app.Grafica6.FontSize = 8;

                        
             wn = app.nEditField.Value ;
             zeta = app.AmortEdit.Value ;
             t = 0:0.1:15 ;
             Amort= exp(-wn * zeta * t) ; 
             plot(app.Grafica1,t,Amort);
             
             A1 = app.A1EditField.Value ;
             A2 = app.A2EditField.Value ;
             
             Osc = (A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t)) ;
             plot(app.Grafica1,t,Osc);
             
           % GRAFICA N°2 POSICION VS TIEMPO
             hold(app.Grafica2,"on");
             grid(app.Grafica2,"on");
             Ut = exp(-wn * zeta * t) .* ((A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t))) ;
           
             plot(app.Grafica2,t,Ut);
             
             
           %GRAFICA N°3 VELOCIDAD VS TIEMPO
            
           hold(app.Grafica3,"on");
           grid(app.Grafica3,"on");
           Veloc = exp(-wn * zeta * t).*(A2*wn*cos(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2)- ...
                                        A1*wn*sin(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2))- ...          
                   wn*zeta*exp(-wn * zeta * t).*(A1*cos(wn*sqrt(1-zeta^2)*t)+A2*sin(wn*sqrt(1-zeta^2)*t))    ;


            plot(app.Grafica3,t,Veloc);
           
           %GRAFICA N°4 FUERZA RESTITUTIVA VS TIEMPO
           hold(app.Grafica4,"on");
           grid(app.Grafica4,"on");
           
           Fr = app.RigidezEdit.Value * Ut ;
           plot(app.Grafica4,t,Fr);
           
           %GRAFICA N°5 FUERZA DE AMORTIGUAMIENTO VS TIEMPO
           hold(app.Grafica5,"on");
           grid(app.Grafica5,"on");
           
           Fd = app.CEditField.Value * Veloc ;
           plot(app.Grafica5,t,Fd);
           
           %GRAFICA N°6 FUERZA DE ACELERACION VS TIEMPO
           hold(app.Grafica6,"on");
           grid(app.Grafica6,"on");
           
           ac= (-Fr - Fd)/app.MasaEdit.Value ; 
           plot(app.Grafica6,t,ac);


        end

        % Value changed function: MasaSlider
        function MasaSliderValueChanged(app, event)
            
            value = app.MasaSlider.Value;
             app.MasaEdit.Value = value;
             
app.Grafica1.XTick = 0:0.2:1;
app.Grafica1.XTickLabel = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
app.Grafica1.YTick = 0:0.2:1;
app.Grafica1.YTickLabel = {'0', '0.2', '0.4', '0.6', '0.8', '1'};

app.Grafica2.XTick = 0:0.2:1;
app.Grafica2.XTickLabel = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
app.Grafica2.YTick = 0:0.2:1;
app.Grafica2.YTickLabel = {'0', '0.2', '0.4', '0.6', '0.8', '1'};

app.Grafica3.XTick = 0:0.2:1;
app.Grafica3.XTickLabel = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
app.Grafica3.YTick = 0:0.2:1;
app.Grafica3.YTickLabel = {'0', '0.2', '0.4', '0.6', '0.8', '1'};

app.Grafica4.XTick = 0:0.2:1;
app.Grafica4.XTickLabel = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
app.Grafica4.YTick = 0:0.2:1;
app.Grafica4.YTickLabel = {'0', '0.2', '0.4', '0.6', '0.8', '1'};

app.Grafica5.XTick = 0:0.2:1;
app.Grafica5.XTickLabel = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
app.Grafica5.YTick = 0:0.2:1;
app.Grafica5.YTickLabel = {'0', '0.2', '0.4', '0.6', '0.8', '1'};

app.Grafica6.XTick = 0:0.2:1;
app.Grafica6.XTickLabel = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
app.Grafica6.YTick = 0:0.2:1;
app.Grafica6.YTickLabel = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
             cla(app.Grafica1,'reset')
            cla(app.Grafica2,'reset')
            cla(app.Grafica3,'reset')
            cla(app.Grafica4,'reset')
            cla(app.Grafica5,'reset')
           cla(app.Grafica6,'reset')
             
             %CALCULAMOS Wn
             app.nEditField.Value = sqrt(app.RigidezEdit.Value / app.MasaEdit.Value) ;
             
             % GRAFICA N°1 AMORTIGUAMIENTO VS OSCILACION
         
             
            hold(app.Grafica1,"on");
             
            title(app.Grafica1, 'AMORTIGUAMIENTO - OSCILACION VS TIEMPO')
            xlabel(app.Grafica1, 'tiempo (t)')
            ylabel(app.Grafica1, '')
            app.Grafica1.FontSize = 8;

            title(app.Grafica2, 'POSICION - TIEMPO')
            xlabel(app.Grafica2, 'tiempo (t)')
            ylabel(app.Grafica2, 'u(t)-(m)')
            app.Grafica2.FontSize = 8;

            title(app.Grafica3, 'VELOCIDAD - TIEMPO')
            xlabel(app.Grafica3, 'tiempo (t)')
            ylabel(app.Grafica3, 'ů(t) - (m/s)')
            app.Grafica3.FontSize = 8;

            title(app.Grafica4, 'FUERZA RESTITUTIVA - TIEMPO')
            xlabel(app.Grafica4, 'tiempo (t)')
            ylabel(app.Grafica4, 'Fr(N)')
            app.Grafica4.FontSize = 8;

            title(app.Grafica5, 'FUERZA DE AMORTIGUAMIENTO - TIEMPO')
            xlabel(app.Grafica5, 'tiempo (t)')
            ylabel(app.Grafica5, 'Fd(N)')
            app.Grafica5.FontSize = 8;

            title(app.Grafica6, 'FUERZA DE ACELERACIÓN - TIEMPO')
            xlabel(app.Grafica6, 'tiempo (t)')
            ylabel(app.Grafica6, 'Fr(N)')
            app.Grafica6.FontSize = 8;
            
             
             wn = app.nEditField.Value ;
             zeta = app.AmortEdit.Value ;
             t = 0:0.1:15 ;
             Amort= exp(-wn * zeta * t) ; 
             plot(app.Grafica1,t,Amort);
             
             A1 = app.A1EditField.Value ;
             A2 = app.A2EditField.Value ;
             
             Osc = (A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t)) ;
             plot(app.Grafica1,t,Osc);
             
             % GRAFICA N°2 POSICION VS TIEMPO
             hold(app.Grafica2,"on");
             grid(app.Grafica2,"on");
             Ut = exp(-wn * zeta * t) .* ((A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t))) ;
           
             plot(app.Grafica2,t,Ut);
           
              %GRAFICA N°3 VELOCIDAD VS TIEMPO
           hold(app.Grafica3,"on");
            grid(app.Grafica3,"on");
           Veloc = exp(-wn * zeta * t).*(A2*wn*cos(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2)- ...
                                        A1*wn*sin(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2))- ...          
                   wn*zeta*exp(-wn * zeta * t).*(A1*cos(wn*sqrt(1-zeta^2)*t)+A2*sin(wn*sqrt(1-zeta^2)*t))    ;


            plot(app.Grafica3,t,Veloc);
             
               %GRAFICA N°4 FUERZA RESTITUTIVA VS TIEMPO
           hold(app.Grafica4,"on");
           grid(app.Grafica4,"on");
           
           Fr = app.RigidezEdit.Value * Ut ;
           plot(app.Grafica4,t,Fr);
           
           
             %GRAFICA N°5 FUERZA DE AMORTIGUAMIENTO VS TIEMPO
           hold(app.Grafica5,"on");
           grid(app.Grafica5,"on");
           
           Fd = app.CEditField.Value * Veloc ;
           plot(app.Grafica5,t,Fd);
           
           %GRAFICA N°6 FUERZA DE ACELERACION VS TIEMPO
           hold(app.Grafica6,"on");
           grid(app.Grafica6,"on");
           
           ac= (-Fr - Fd)/app.MasaEdit.Value ; 
           plot(app.Grafica6,t,ac);
        end

        % Value changed function: RigidezSlider
        function RigidezSliderValueChanged(app, event)
            value = app.RigidezSlider.Value;
            app.RigidezEdit.Value = value;
            
            cla(app.Grafica1,'reset')
            cla(app.Grafica2,'reset')
            cla(app.Grafica3,'reset')
            cla(app.Grafica4,'reset')
             cla(app.Grafica5,'reset')
           cla(app.Grafica6,'reset')
            
            %CALCULAMOS Wn
            app.nEditField.Value = sqrt(app.RigidezEdit.Value / app.MasaEdit.Value) ;
            
            %CALCULAMOS Tn
             app.TnEditField.Value = (2*pi)/app.nEditField.Value ;
             
          % GRAFICA N°1 AMORTIGUAMIENTO VS OSCILACION
             hold(app.Grafica1,"on");
             grid(app.Grafica1,"on");
            title(app.Grafica1, 'AMORTIGUAMIENTO - OSCILACION VS TIEMPO')
            xlabel(app.Grafica1, 'tiempo (t)')
            ylabel(app.Grafica1, '')
            app.Grafica1.FontSize = 8;
                         title(app.Grafica2, 'POSICION - TIEMPO')
            xlabel(app.Grafica2, 'tiempo (t)')
            ylabel(app.Grafica2, 'u(t)-(m)')
            app.Grafica2.FontSize = 8;

            title(app.Grafica3, 'VELOCIDAD - TIEMPO')
            xlabel(app.Grafica3, 'tiempo (t)')
            ylabel(app.Grafica3, 'ů(t) - (m/s)')
            app.Grafica3.FontSize = 8;

            title(app.Grafica4, 'FUERZA RESTITUTIVA - TIEMPO')
            xlabel(app.Grafica4, 'tiempo (t)')
            ylabel(app.Grafica4, 'Fr(N)')
            app.Grafica4.FontSize = 8;

            title(app.Grafica5, 'FUERZA DE AMORTIGUAMIENTO - TIEMPO')
            xlabel(app.Grafica5, 'tiempo (t)')
            ylabel(app.Grafica5, 'Fd(N)')
            app.Grafica5.FontSize = 8;

            title(app.Grafica6, 'FUERZA DE ACELERACIÓN - TIEMPO')
            xlabel(app.Grafica6, 'tiempo (t)')
            ylabel(app.Grafica6, 'Fr(N)')
            app.Grafica6.FontSize = 8;
             
             wn = app.nEditField.Value ;
             zeta = app.AmortEdit.Value ;
             t = 0:0.1:15 ;
             Amort= exp(-wn * zeta * t) ; 
             plot(app.Grafica1,t,Amort);
             
             A1 = app.A1EditField.Value ;
             A2 = app.A2EditField.Value ;
             
             Osc = (A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t)) ;
             plot(app.Grafica1,t,Osc);


           % GRAFICA N°2 POSICION VS TIEMPO
             hold(app.Grafica2,"on");
             grid(app.Grafica2,"on");
             Ut = exp(-wn * zeta * t) .* ((A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t))) ;
           
             plot(app.Grafica2,t,Ut);
           
           %GRAFICA N°3 VELOCIDAD VS TIEMPO
           hold(app.Grafica3,"on");
            grid(app.Grafica3,"on");
           Veloc = exp(-wn * zeta * t).*(A2*wn*cos(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2)- ...
                                        A1*wn*sin(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2))- ...          
                   wn*zeta*exp(-wn * zeta * t).*(A1*cos(wn*sqrt(1-zeta^2)*t)+A2*sin(wn*sqrt(1-zeta^2)*t))    ;


            plot(app.Grafica3,t,Veloc); 
             
            %GRAFICA N°4 FUERZA RESTITUTIVA VS TIEMPO
           hold(app.Grafica4,"on");
           grid(app.Grafica4,"on");
           
           Fr = app.RigidezEdit.Value * Ut ;
           plot(app.Grafica4,t,Fr);
           
           %GRAFICA N°5 FUERZA DE AMORTIGUAMIENTO VS TIEMPO
           hold(app.Grafica5,"on");
           grid(app.Grafica5,"on");
           
           Fd = app.CEditField.Value * Veloc ;
           plot(app.Grafica5,t,Fd);
            %GRAFICA N°6 FUERZA DE ACELERACION VS TIEMPO
           hold(app.Grafica6,"on");
           grid(app.Grafica6,"on");
           
           ac= (-Fr - Fd)/app.MasaEdit.Value ; 
           plot(app.Grafica6,t,ac); 
        end

        % Value changed function: AmortSlider
        function AmortSliderValueChanged(app, event)
            value = app.AmortSlider.Value;
            app.AmortEdit.Value = value;
            
            cla(app.Grafica1,'reset')
            cla(app.Grafica2,'reset')
            cla(app.Grafica3,'reset')
%             cla(app.Grafica4,'reset')
           
            
            % Calculamos C
            app.CEditField.Value = app.AmortEdit.Value * 2 * sqrt(app.RigidezEdit.Value * app.MasaEdit.Value) ;
            
            % CALCULO DE A2
            
             app.A2EditField.Value = (app.VelocEdit.Value + app.nEditField.Value * app.AmortEdit.Value * app.PosiEdit.Value ) / (app.nEditField.Value * sqrt(1-app.AmortEdit.Value^2) ) ;
            
            
           % GRAFICA N°1 AMORTIGUAMIENTO VS OSCILACION
             hold(app.Grafica1,"on");
             grid(app.Grafica1,"on");
                    title(app.Grafica1, 'AMORTIGUAMIENTO - OSCILACION VS TIEMPO')
            xlabel(app.Grafica1, 'tiempo (t)')
            ylabel(app.Grafica1, '')
            app.Grafica1.FontSize = 8;     
                         title(app.Grafica2, 'POSICION - TIEMPO')
            xlabel(app.Grafica2, 'tiempo (t)')
            ylabel(app.Grafica2, 'u(t)-(m)')
            app.Grafica2.FontSize = 8;

            title(app.Grafica3, 'VELOCIDAD - TIEMPO')
            xlabel(app.Grafica3, 'tiempo (t)')
            ylabel(app.Grafica3, 'ů(t) - (m/s)')
            app.Grafica3.FontSize = 8;

            title(app.Grafica4, 'FUERZA RESTITUTIVA - TIEMPO')
            xlabel(app.Grafica4, 'tiempo (t)')
            ylabel(app.Grafica4, 'Fr(N)')
            app.Grafica4.FontSize = 8;

            title(app.Grafica5, 'FUERZA DE AMORTIGUAMIENTO - TIEMPO')
            xlabel(app.Grafica5, 'tiempo (t)')
            ylabel(app.Grafica5, 'Fd(N)')
            app.Grafica5.FontSize = 8;

            title(app.Grafica6, 'FUERZA DE ACELERACIÓN - TIEMPO')
            xlabel(app.Grafica6, 'tiempo (t)')
            ylabel(app.Grafica6, 'Fr(N)')
            app.Grafica6.FontSize = 8;
             wn = app.nEditField.Value ;
             zeta = app.AmortEdit.Value ;
             t = 0:0.1:15 ;
             Amort= exp(-wn * zeta * t) ; 
             plot(app.Grafica1,t,Amort);
             
             A1 = app.A1EditField.Value ;
             A2 = app.A2EditField.Value ;
             
             Osc = (A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t)) ;
             plot(app.Grafica1,t,Osc); 


           % GRAFICA N°2 POSICION VS TIEMPO
             hold(app.Grafica2,"on");
             grid(app.Grafica2,"on");
             Ut = exp(-wn * zeta * t) .* ((A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t))) ;
           
             plot(app.Grafica2,t,Ut);
           
            %GRAFICA N°3 VELOCIDAD VS TIEMPO
           hold(app.Grafica3,"on");
            grid(app.Grafica3,"on");
           Veloc = exp(-wn * zeta * t).*(A2*wn*cos(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2)- ...
                                        A1*wn*sin(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2))- ...          
                   wn*zeta*exp(-wn * zeta * t).*(A1*cos(wn*sqrt(1-zeta^2)*t)+A2*sin(wn*sqrt(1-zeta^2)*t))    ;
            plot(app.Grafica3,t,Veloc);  
            
%              %GRAFICA N°4 FUERZA RESTITUTIVA VS TIEMPO
%            hold(app.Grafica4,"on");
%            grid(app.Grafica4,"on");
%            
%            Fr = app.RigidezEdit.Value * Ut ;
%            plot(app.Grafica4,t,Fr);
             
        end

        % Value changed function: PosiSlider
        function PosiSliderValueChanged(app, event)
            value = app.PosiSlider.Value;
            app.PosiEdit.Value= value;
            
            cla(app.Grafica1,'reset')
            cla(app.Grafica2,'reset')
            cla(app.Grafica3,'reset')
            cla(app.Grafica4,'reset')
            cla(app.Grafica5,'reset')
           cla(app.Grafica6,'reset')
           %CALCULO DE A1 
           app.A1EditField.Value = app.PosiSlider.Value ;
           
           
            % GRAFICA N°1 AMORTIGUAMIENTO VS OSCILACION
             hold(app.Grafica1,"on");
             grid(app.Grafica1,"on");
                         title(app.Grafica1, 'AMORTIGUAMIENTO - OSCILACION VS TIEMPO')
            xlabel(app.Grafica1, 'tiempo (t)')
            ylabel(app.Grafica1, '')
            app.Grafica1.FontSize = 8;
            title(app.Grafica2, 'POSICION - TIEMPO')
            xlabel(app.Grafica2, 'tiempo (t)')
            ylabel(app.Grafica2, 'u(t)-(m)')
            app.Grafica2.FontSize = 8;

            title(app.Grafica3, 'VELOCIDAD - TIEMPO')
            xlabel(app.Grafica3, 'tiempo (t)')
            ylabel(app.Grafica3, 'ů(t) - (m/s)')
            app.Grafica3.FontSize = 8;

            title(app.Grafica4, 'FUERZA RESTITUTIVA - TIEMPO')
            xlabel(app.Grafica4, 'tiempo (t)')
            ylabel(app.Grafica4, 'Fr(N)')
            app.Grafica4.FontSize = 8;

            title(app.Grafica5, 'FUERZA DE AMORTIGUAMIENTO - TIEMPO')
            xlabel(app.Grafica5, 'tiempo (t)')
            ylabel(app.Grafica5, 'Fd(N)')
            app.Grafica5.FontSize = 8;

            title(app.Grafica6, 'FUERZA DE ACELERACIÓN - TIEMPO')
            xlabel(app.Grafica6, 'tiempo (t)')
            ylabel(app.Grafica6, 'Fr(N)')
            app.Grafica6.FontSize = 8; 
             wn = app.nEditField.Value ;
             zeta = app.AmortEdit.Value ;
             t = 0:0.1:15 ;
             Amort= exp(-wn * zeta * t) ; 
             plot(app.Grafica1,t,Amort);
             
             A1 = app.A1EditField.Value ;
             A2 = app.A2EditField.Value ;
             
             Osc = (A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t)) ;
             plot(app.Grafica1,t,Osc);
             
               
           % GRAFICA N°2 POSICION VS TIEMPO
             hold(app.Grafica2,"on");
             grid(app.Grafica2,"on");
             Ut = exp(-wn * zeta * t) .* ((A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t))) ;
           
             plot(app.Grafica2,t,Ut);
           
            %GRAFICA N°3 VELOCIDAD VS TIEMPO
           hold(app.Grafica3,"on");
            grid(app.Grafica3,"on");
           Veloc = exp(-wn * zeta * t).*(A2*wn*cos(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2)- ...
                                        A1*wn*sin(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2))- ...          
                   wn*zeta*exp(-wn * zeta * t).*(A1*cos(wn*sqrt(1-zeta^2)*t)+A2*sin(wn*sqrt(1-zeta^2)*t))    ;


            plot(app.Grafica3,t,Veloc);  


            %GRAFICA N°4 FUERZA RESTITUTIVA VS TIEMPO
           hold(app.Grafica4,"on");
           grid(app.Grafica4,"on");
           
           Fr = app.RigidezEdit.Value * Ut ;
           plot(app.Grafica4,t,Fr);
           
               %GRAFICA N°5 FUERZA DE AMORTIGUAMIENTO VS TIEMPO
           hold(app.Grafica5,"on");
           grid(app.Grafica5,"on");
           
           Fd = app.CEditField.Value * Veloc ;
           plot(app.Grafica5,t,Fd);
           %GRAFICA N°6 FUERZA DE ACELERACION VS TIEMPO
           hold(app.Grafica6,"on");
           grid(app.Grafica6,"on");
           
           ac= (-Fr - Fd)/app.MasaEdit.Value ; 
           plot(app.Grafica6,t,ac);
        end

        % Value changed function: VelocLSlider
        function VelocLSliderValueChanged(app, event)
            value = app.VelocLSlider.Value;
            app.VelocEdit.Value = value;
                        
           cla(app.Grafica1,'reset')
            cla(app.Grafica2,'reset')
            cla(app.Grafica3,'reset')
            cla(app.Grafica4,'reset')
            cla(app.Grafica5,'reset')
           cla(app.Grafica6,'reset')
            
           % GRAFICA N°1 AMORTIGUAMIENTO VS OSCILACION

                       title(app.Grafica1, 'AMORTIGUAMIENTO - OSCILACION VS TIEMPO')
            xlabel(app.Grafica1, 'tiempo (t)')
            ylabel(app.Grafica1, '')
            app.Grafica1.FontSize = 8;
             hold(app.Grafica1,"on");
             grid(app.Grafica1,"on");
                         title(app.Grafica2, 'POSICION - TIEMPO')
            xlabel(app.Grafica2, 'tiempo (t)')
            ylabel(app.Grafica2, 'u(t)-(m)')
            app.Grafica2.FontSize = 8;

            title(app.Grafica3, 'VELOCIDAD - TIEMPO')
            xlabel(app.Grafica3, 'tiempo (t)')
            ylabel(app.Grafica3, 'ů(t) - (m/s)')
            app.Grafica3.FontSize = 8;

            title(app.Grafica4, 'FUERZA RESTITUTIVA - TIEMPO')
            xlabel(app.Grafica4, 'tiempo (t)')
            ylabel(app.Grafica4, 'Fr(N)')
            app.Grafica4.FontSize = 8;

            title(app.Grafica5, 'FUERZA DE AMORTIGUAMIENTO - TIEMPO')
            xlabel(app.Grafica5, 'tiempo (t)')
            ylabel(app.Grafica5, 'Fd(N)')
            app.Grafica5.FontSize = 8;

            title(app.Grafica6, 'FUERZA DE ACELERACIÓN - TIEMPO')
            xlabel(app.Grafica6, 'tiempo (t)')
            ylabel(app.Grafica6, 'Fr(N)')
            app.Grafica6.FontSize = 8;
             wn = app.nEditField.Value ;
             zeta = app.AmortEdit.Value ;
             t = 0:0.1:15 ;
             Amort= exp(-wn * zeta * t) ; 
             plot(app.Grafica1,t,Amort);
             
             A1 = app.A1EditField.Value ;
             A2 = app.A2EditField.Value ;
             
             Osc = (A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t)) ;
             plot(app.Grafica1,t,Osc); 
             
               
           % GRAFICA N°2 POSICION VS TIEMPO
             hold(app.Grafica2,"on");
             grid(app.Grafica2,"on");
             Ut = exp(-wn * zeta * t) .* ((A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t))) ;
           
             plot(app.Grafica2,t,Ut);
           
             %GRAFICA N°3 VELOCIDAD VS TIEMPO
            hold(app.Grafica3,"on");
            grid(app.Grafica3,"on");
           Veloc = exp(-wn * zeta * t).*(A2*wn*cos(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2)- ...
                                        A1*wn*sin(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2))- ...          
                   wn*zeta*exp(-wn * zeta * t).*(A1*cos(wn*sqrt(1-zeta^2)*t)+A2*sin(wn*sqrt(1-zeta^2)*t))    ;


            plot(app.Grafica3,t,Veloc); 
            
            %GRAFICA N°4 FUERZA RESTITUTIVA VS TIEMPO
           hold(app.Grafica4,"on");
           grid(app.Grafica4,"on");
           
           Fr = app.RigidezEdit.Value * Ut ;
           plot(app.Grafica4,t,Fr);
            
             %GRAFICA N°5 FUERZA DE AMORTIGUAMIENTO VS TIEMPO
           hold(app.Grafica5,"on");
           grid(app.Grafica5,"on");
           
           Fd = app.CEditField.Value * Veloc ;
           plot(app.Grafica5,t,Fd);
            %GRAFICA N°6 FUERZA DE ACELERACION VS TIEMPO
           hold(app.Grafica6,"on");
           grid(app.Grafica6,"on");
           
           ac= (-Fr - Fd)/app.MasaEdit.Value ; 
           plot(app.Grafica6,t,ac); 
        end

        % Value changed function: TnEditField
        function TnEditFieldValueChanged(app, event)
            value = app.TnEditField.Value;                     
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;    
        end

        % Value changed function: RigidezEdit
        function RigidezEditValueChanged(app, event)
            value = app.RigidezEdit.Value;
            app.RigidezSlider.Value = value;
            
            
            cla(app.Grafica1,'reset')
            cla(app.Grafica2,'reset')
            cla(app.Grafica3,'reset')
            cla(app.Grafica4,'reset')
            cla(app.Grafica5,'reset')
           cla(app.Grafica6,'reset')
             %CALCULAMOS Wn
            app.nEditField.Value = sqrt(app.RigidezEdit.Value / app.MasaEdit.Value) ;
            
            %CALCULAMOS Tn
             app.TnEditField.Value = (2*pi)/app.nEditField.Value ;
             
           % GRAFICA N°1 AMORTIGUAMIENTO VS OSCILACION
             hold(app.Grafica1,"on");
             grid(app.Grafica1,"on");

                         title(app.Grafica1, 'AMORTIGUAMIENTO - OSCILACION VS TIEMPO')
            xlabel(app.Grafica1, 'tiempo (t)')
            ylabel(app.Grafica1, '')
            app.Grafica1.FontSize = 8;
                         title(app.Grafica2, 'POSICION - TIEMPO')
            xlabel(app.Grafica2, 'tiempo (t)')
            ylabel(app.Grafica2, 'u(t)-(m)')
            app.Grafica2.FontSize = 8;

            title(app.Grafica3, 'VELOCIDAD - TIEMPO')
            xlabel(app.Grafica3, 'tiempo (t)')
            ylabel(app.Grafica3, 'ů(t) - (m/s)')
            app.Grafica3.FontSize = 8;

            title(app.Grafica4, 'FUERZA RESTITUTIVA - TIEMPO')
            xlabel(app.Grafica4, 'tiempo (t)')
            ylabel(app.Grafica4, 'Fr(N)')
            app.Grafica4.FontSize = 8;

            title(app.Grafica5, 'FUERZA DE AMORTIGUAMIENTO - TIEMPO')
            xlabel(app.Grafica5, 'tiempo (t)')
            ylabel(app.Grafica5, 'Fd(N)')
            app.Grafica5.FontSize = 8;

            title(app.Grafica6, 'FUERZA DE ACELERACIÓN - TIEMPO')
            xlabel(app.Grafica6, 'tiempo (t)')
            ylabel(app.Grafica6, 'Fr(N)')
            app.Grafica6.FontSize = 8;
             wn = app.nEditField.Value ;
             zeta = app.AmortEdit.Value ;
             t = 0:0.1:15 ;
             Amort= exp(-wn * zeta * t) ; 
             plot(app.Grafica1,t,Amort);
             
             A1 = app.A1EditField.Value ;
             A2 = app.A2EditField.Value ;
             
             Osc = (A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t)) ;
             plot(app.Grafica1,t,Osc);
             
               
           % GRAFICA N°2 POSICION VS TIEMPO
             hold(app.Grafica2,"on");
             grid(app.Grafica2,"on");
             Ut = exp(-wn * zeta * t) .* ((A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t))) ;
           
             plot(app.Grafica2,t,Ut);
           
             %GRAFICA N°3 VELOCIDAD VS TIEMPO
           
            hold(app.Grafica3,"on");
            grid(app.Grafica3,"on");
           Veloc = exp(-wn * zeta * t).*(A2*wn*cos(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2)- ...
                                        A1*wn*sin(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2))- ...          
                   wn*zeta*exp(-wn * zeta * t).*(A1*cos(wn*sqrt(1-zeta^2)*t)+A2*sin(wn*sqrt(1-zeta^2)*t))    ;
           
           
            plot(app.Grafica3,t,Veloc); 
             
             %GRAFICA N°4 FUERZA RESTITUTIVA VS TIEMPO
           hold(app.Grafica4,"on");
           grid(app.Grafica4,"on");
           
           Fr = app.RigidezEdit.Value * Ut ;
           plot(app.Grafica4,t,Fr);
           
            %GRAFICA N°5 FUERZA DE AMORTIGUAMIENTO VS TIEMPO
           hold(app.Grafica5,"on");
           grid(app.Grafica5,"on");
           
           Fd = app.CEditField.Value * Veloc ;
           plot(app.Grafica5,t,Fd);
            %GRAFICA N°6 FUERZA DE ACELERACION VS TIEMPO
           hold(app.Grafica6,"on");
           grid(app.Grafica6,"on");
           
           ac= (-Fr - Fd)/app.MasaEdit.Value ; 
           plot(app.Grafica6,t,ac); 
        end

        % Value changed function: AmortEdit
        function AmortEditValueChanged(app, event)
            value = app.AmortEdit.Value;
            app.AmortSlider.Value = value;
            
            
            cla(app.Grafica1,'reset')
            cla(app.Grafica2,'reset')
            cla(app.Grafica3,'reset')
%             cla(app.Grafica4,'reset')
           
             % Calculamos C
            app.CEditField.Value = app.AmortEdit.Value * 2 * sqrt(app.RigidezEdit.Value * app.MasaEdit.Value) ;


          % GRAFICA N°1 AMORTIGUAMIENTO VS OSCILACION
             hold(app.Grafica1,"on");
             grid(app.Grafica1,"on");

                         title(app.Grafica1, 'AMORTIGUAMIENTO - OSCILACION VS TIEMPO')
            xlabel(app.Grafica1, 'tiempo (t)')
            ylabel(app.Grafica1, '')
            app.Grafica1.FontSize = 8;
                         title(app.Grafica2, 'POSICION - TIEMPO')
            xlabel(app.Grafica2, 'tiempo (t)')
            ylabel(app.Grafica2, 'u(t)-(m)')
            app.Grafica2.FontSize = 8;

            title(app.Grafica3, 'VELOCIDAD - TIEMPO')
            xlabel(app.Grafica3, 'tiempo (t)')
            ylabel(app.Grafica3, 'ů(t) - (m/s)')
            app.Grafica3.FontSize = 8;

            title(app.Grafica4, 'FUERZA RESTITUTIVA - TIEMPO')
            xlabel(app.Grafica4, 'tiempo (t)')
            ylabel(app.Grafica4, 'Fr(N)')
            app.Grafica4.FontSize = 8;

            title(app.Grafica5, 'FUERZA DE AMORTIGUAMIENTO - TIEMPO')
            xlabel(app.Grafica5, 'tiempo (t)')
            ylabel(app.Grafica5, 'Fd(N)')
            app.Grafica5.FontSize = 8;

            title(app.Grafica6, 'FUERZA DE ACELERACIÓN - TIEMPO')
            xlabel(app.Grafica6, 'tiempo (t)')
            ylabel(app.Grafica6, 'Fr(N)')
            app.Grafica6.FontSize = 8;
             wn = app.nEditField.Value ;
             zeta = app.AmortEdit.Value ;
             t = 0:0.1:15 ;
             Amort= exp(-wn * zeta * t) ; 
             plot(app.Grafica1,t,Amort);
             
             A1 = app.A1EditField.Value ;
             A2 = app.A2EditField.Value ;
             
             Osc = (A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t)) ;
             plot(app.Grafica1,t,Osc);
             
               
           % GRAFICA N°2 POSICION VS TIEMPO
             hold(app.Grafica2,"on");
             grid(app.Grafica2,"on");
             Ut = exp(-wn * zeta * t) .* ((A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t))) ;
           
             plot(app.Grafica2,t,Ut);
           
             
             %GRAFICA N°3 VELOCIDAD VS TIEMPO
           hold(app.Grafica3,"on");
            grid(app.Grafica3,"on");
           Veloc = exp(-wn * zeta * t).*(A2*wn*cos(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2)- ...
                                        A1*wn*sin(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2))- ...          
                   wn*zeta*exp(-wn * zeta * t).*(A1*cos(wn*sqrt(1-zeta^2)*t)+A2*sin(wn*sqrt(1-zeta^2)*t))    ;


            plot(app.Grafica3,t,Veloc); 
            
%             %GRAFICA N°4 FUERZA RESTITUTIVA VS TIEMPO
%            hold(app.Grafica4,"on");
%            grid(app.Grafica4,"on");
%            
%            Fr = app.RigidezEdit.Value * Ut ;
%            plot(app.Grafica4,t,Fr);
%             
            
             
        end

        % Value changed function: PosiEdit
        function PosiEditValueChanged(app, event)
            value = app.PosiEdit.Value;
            app.PosiSlider.Value = value;


            cla(app.Grafica1,'reset')
            cla(app.Grafica2,'reset')
            cla(app.Grafica3,'reset')
            cla(app.Grafica4,'reset')
            cla(app.Grafica5,'reset')
           cla(app.Grafica6,'reset')
            %CALCULO DE A1
            app.A1EditField.Value = app.PosiEdit.Value ;


         % GRAFICA N°1 AMORTIGUAMIENTO VS OSCILACION
             hold(app.Grafica1,"on");
             grid(app.Grafica1,"on");

                         title(app.Grafica1, 'AMORTIGUAMIENTO - OSCILACION VS TIEMPO')
            xlabel(app.Grafica1, 'tiempo (t)')
            ylabel(app.Grafica1, '')
            app.Grafica1.FontSize = 8;
                      title(app.Grafica2, 'POSICION - TIEMPO')
            xlabel(app.Grafica2, 'tiempo (t)')
            ylabel(app.Grafica2, 'u(t)-(m)')
            app.Grafica2.FontSize = 8;

            title(app.Grafica3, 'VELOCIDAD - TIEMPO')
            xlabel(app.Grafica3, 'tiempo (t)')
            ylabel(app.Grafica3, 'ů(t) - (m/s)')
            app.Grafica3.FontSize = 8;

            title(app.Grafica4, 'FUERZA RESTITUTIVA - TIEMPO')
            xlabel(app.Grafica4, 'tiempo (t)')
            ylabel(app.Grafica4, 'Fr(N)')
            app.Grafica4.FontSize = 8;

            title(app.Grafica5, 'FUERZA DE AMORTIGUAMIENTO - TIEMPO')
            xlabel(app.Grafica5, 'tiempo (t)')
            ylabel(app.Grafica5, 'Fd(N)')
            app.Grafica5.FontSize = 8;

            title(app.Grafica6, 'FUERZA DE ACELERACIÓN - TIEMPO')
            xlabel(app.Grafica6, 'tiempo (t)')
            ylabel(app.Grafica6, 'Fr(N)')
            app.Grafica6.FontSize = 8;   
             wn = app.nEditField.Value ;
             zeta = app.AmortEdit.Value ;
             t = 0:0.1:15 ;
             Amort= exp(-wn * zeta * t) ; 
             plot(app.Grafica1,t,Amort);
             
             A1 = app.A1EditField.Value ;
             A2 = app.A2EditField.Value ;
             
             Osc = (A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t)) ;
             plot(app.Grafica1,t,Osc);
             
           
           % GRAFICA N°2 POSICION VS TIEMPO
             hold(app.Grafica2,"on");
             grid(app.Grafica2,"on");
             Ut = exp(-wn * zeta * t) .* ((A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t))) ;
           
             plot(app.Grafica2,t,Ut);
               
             
             %GRAFICA N°3 VELOCIDAD VS TIEMPO
           hold(app.Grafica3,"on");
            grid(app.Grafica3,"on");
           Veloc = exp(-wn * zeta * t).*(A2*wn*cos(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2)- ...
                                        A1*wn*sin(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2))- ...          
                   wn*zeta*exp(-wn * zeta * t).*(A1*cos(wn*sqrt(1-zeta^2)*t)+A2*sin(wn*sqrt(1-zeta^2)*t))    ;


            plot(app.Grafica3,t,Veloc); 
             
           %GRAFICA N°4 FUERZA RESTITUTIVA VS TIEMPO
           hold(app.Grafica4,"on");
           grid(app.Grafica4,"on");
           
           Fr = app.RigidezEdit.Value * Ut ;
           plot(app.Grafica4,t,Fr);  
           
           
           %GRAFICA N°5 FUERZA DE AMORTIGUAMIENTO VS TIEMPO
           hold(app.Grafica5,"on");
           grid(app.Grafica5,"on");
           
           Fd = app.CEditField.Value * Veloc ;
           plot(app.Grafica5,t,Fd);
             
          %GRAFICA N°6 FUERZA DE ACELERACION VS TIEMPO
           hold(app.Grafica6,"on");
           grid(app.Grafica6,"on");
           
           ac= (-Fr - Fd)/app.MasaEdit.Value ; 
           plot(app.Grafica6,t,ac); 
        end

        % Value changed function: VelocEdit
        function VelocEditValueChanged(app, event)
            value = app.VelocEdit.Value;
            app.VelocLSlider.Value = value;
            
            cla(app.Grafica1,'reset')
            cla(app.Grafica2,'reset')
            cla(app.Grafica3,'reset')
            cla(app.Grafica4,'reset')
            cla(app.Grafica5,'reset')
           cla(app.Grafica6,'reset')
                    title(app.Grafica1, 'AMORTIGUAMIENTO - OSCILACION VS TIEMPO')
            xlabel(app.Grafica1, 'tiempo (t)')
            ylabel(app.Grafica1, '')
            app.Grafica1.FontSize = 8;    
                   title(app.Grafica2, 'POSICION - TIEMPO')
            xlabel(app.Grafica2, 'tiempo (t)')
            ylabel(app.Grafica2, 'u(t)-(m)')
            app.Grafica2.FontSize = 8;

            title(app.Grafica3, 'VELOCIDAD - TIEMPO')
            xlabel(app.Grafica3, 'tiempo (t)')
            ylabel(app.Grafica3, 'ů(t) - (m/s)')
            app.Grafica3.FontSize = 8;

            title(app.Grafica4, 'FUERZA RESTITUTIVA - TIEMPO')
            xlabel(app.Grafica4, 'tiempo (t)')
            ylabel(app.Grafica4, 'Fr(N)')
            app.Grafica4.FontSize = 8;

            title(app.Grafica5, 'FUERZA DE AMORTIGUAMIENTO - TIEMPO')
            xlabel(app.Grafica5, 'tiempo (t)')
            ylabel(app.Grafica5, 'Fd(N)')
            app.Grafica5.FontSize = 8;

            title(app.Grafica6, 'FUERZA DE ACELERACIÓN - TIEMPO')
            xlabel(app.Grafica6, 'tiempo (t)')
            ylabel(app.Grafica6, 'Fr(N)')
            app.Grafica6.FontSize = 8;    
          % GRAFICA N°1 AMORTIGUAMIENTO VS OSCILACION
             hold(app.Grafica1,"on");
             grid(app.Grafica1,"on");
             
             wn = app.nEditField.Value ;
             zeta = app.AmortEdit.Value ;
             t = 0:0.1:15 ;
             Amort= exp(-wn * zeta * t) ; 
             plot(app.Grafica1,t,Amort);
             
             A1 = app.A1EditField.Value ;
             A2 = app.A2EditField.Value ;
             
             Osc = (A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t)) ;
             plot(app.Grafica1,t,Osc);
            
            
           % GRAFICA N°2 POSICION VS TIEMPO
             hold(app.Grafica2,"on");
             grid(app.Grafica2,"on");
             Ut = exp(-wn * zeta * t) .* ((A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t))) ;
           
             plot(app.Grafica2,t,Ut);
            
             
             %GRAFICA N°3 VELOCIDAD VS TIEMPO
           hold(app.Grafica3,"on");
            grid(app.Grafica3,"on");
           Veloc = exp(-wn * zeta * t).*(A2*wn*cos(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2)- ...
                                        A1*wn*sin(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2))- ...          
                   wn*zeta*exp(-wn * zeta * t).*(A1*cos(wn*sqrt(1-zeta^2)*t)+A2*sin(wn*sqrt(1-zeta^2)*t))    ;


            plot(app.Grafica3,t,Veloc); 
             
            %GRAFICA N°4 FUERZA RESTITUTIVA VS TIEMPO
           hold(app.Grafica4,"on");
           grid(app.Grafica4,"on");
           
           Fr = app.RigidezEdit.Value * Ut ;
           plot(app.Grafica4,t,Fr); 
             
           %GRAFICA N°5 FUERZA DE AMORTIGUAMIENTO VS TIEMPO
           hold(app.Grafica5,"on");
           grid(app.Grafica5,"on");
           
           Fd = app.CEditField.Value * Veloc ;
           plot(app.Grafica5,t,Fd);
           
           
           %GRAFICA N°6 FUERZA DE ACELERACION VS TIEMPO
           hold(app.Grafica6,"on");
           grid(app.Grafica6,"on");
           
           ac= (-Fr - Fd)/app.MasaEdit.Value ; 
           plot(app.Grafica6,t,ac);
        end

        % Value changed function: A1EditField
        function A1EditFieldValueChanged(app, event)
            value = app.A1EditField.Value;
            
        end

        % Value changed function: A2EditField
        function A2EditFieldValueChanged(app, event)
            value = app.A2EditField.Value;
            
        end

        % Callback function
        function GraficarPushed(app, event)
              
            cla(app.Grafica1,'reset')
            cla(app.Grafica2,'reset')
            cla(app.Grafica3,'reset')
            cla(app.Grafica4,'reset')
            cla(app.Grafica5,'reset')
           cla(app.Grafica6,'reset')
            % GRAFICA N°1 AMORTIGUAMIENTO VS OSCILACION
             hold(app.Grafica1,"on");
             grid(app.Grafica1,"on");
                         title(app.Grafica1, 'AMORTIGUAMIENTO - OSCILACION VS TIEMPO')
            xlabel(app.Grafica1, 'tiempo (t)')
            ylabel(app.Grafica1, '')
            app.Grafica1.FontSize = 8;
                 title(app.Grafica2, 'POSICION - TIEMPO')
            xlabel(app.Grafica2, 'tiempo (t)')
            ylabel(app.Grafica2, 'u(t)-(m)')
            app.Grafica2.FontSize = 8;

            title(app.Grafica3, 'VELOCIDAD - TIEMPO')
            xlabel(app.Grafica3, 'tiempo (t)')
            ylabel(app.Grafica3, 'ů(t) - (m/s)')
            app.Grafica3.FontSize = 8;

            title(app.Grafica4, 'FUERZA RESTITUTIVA - TIEMPO')
            xlabel(app.Grafica4, 'tiempo (t)')
            ylabel(app.Grafica4, 'Fr(N)')
            app.Grafica4.FontSize = 8;

            title(app.Grafica5, 'FUERZA DE AMORTIGUAMIENTO - TIEMPO')
            xlabel(app.Grafica5, 'tiempo (t)')
            ylabel(app.Grafica5, 'Fd(N)')
            app.Grafica5.FontSize = 8;

            title(app.Grafica6, 'FUERZA DE ACELERACIÓN - TIEMPO')
            xlabel(app.Grafica6, 'tiempo (t)')
            ylabel(app.Grafica6, 'Fr(N)')
            app.Grafica6.FontSize = 8;        
             wn = app.nEditField.Value ;
             zeta = app.AmortEdit.Value ;
             t = 0:0.1:15 ;
             Amort= exp(-wn * zeta * t) ; 
             plot(app.Grafica1,t,Amort);
             
             A1 = app.A1EditField.Value ;
             A2 = app.A2EditField.Value ;
             
             Osc = (A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t)) ;
             plot(app.Grafica1,t,Osc);
             
              
           % GRAFICA N°2 POSICION VS TIEMPO
             hold(app.Grafica2,"on");
             grid(app.Grafica2,"on");
             Ut = exp(-wn * zeta * t) .* ((A1*cos(wn*sqrt(1-zeta^2)*t)) + (A2*sin(wn*sqrt(1-zeta^2)*t))) ;
           
             plot(app.Grafica2,t,Ut);
            
             
             %GRAFICA N°3 VELOCIDAD VS TIEMPO
            hold(app.Grafica3,"on");
            grid(app.Grafica3,"on");
           Veloc = exp(-wn * zeta * t).*(A2*wn*cos(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2)- ...
                                        A1*wn*sin(wn*sqrt(1-zeta^2)*t)*sqrt(1-zeta^2))- ...          
                   wn*zeta*exp(-wn * zeta * t).*(A1*cos(wn*sqrt(1-zeta^2)*t)+A2*sin(wn*sqrt(1-zeta^2)*t))    ;


            plot(app.Grafica3,t,Veloc); 
             
            %GRAFICA N°4 FUERZA RESTITUTIVA VS TIEMPO
           hold(app.Grafica4,"on");
           grid(app.Grafica4,"on");
           
           Fr = app.RigidezEdit.Value * Ut ;
           plot(app.Grafica4,t,Fr); 
             
             %GRAFICA N°5 FUERZA DE AMORTIGUAMIENTO VS TIEMPO
           hold(app.Grafica5,"on");
           grid(app.Grafica5,"on");
           
           Fd = app.CEditField.Value * Veloc ;
           plot(app.Grafica5,t,Fd); 
           
             %GRAFICA N°6 FUERZA DE ACELERACION VS TIEMPO
           hold(app.Grafica6,"on");
           grid(app.Grafica6,"on");
           
           ac= (-Fr - Fd)/app.MasaEdit.Value ; 
           plot(app.Grafica6,t,ac);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GRADEONEUIFigure = uifigure('Visible', 'off');
            app.GRADEONEUIFigure.Name = 'GRADEONE';
            app.GRADEONEUIFigure.Icon = fullfile(pathToMLAPP, 'seismograph_10522466.ico');
            app.Grafica1 = uiaxes(app.GRADEONEUIFigure);
            title(app.Grafica1, 'AMORTIGUAMIENTO - OSCILACION VS TIEMPO')
            xlabel(app.Grafica1, 'tiempo (t)')
            app.Grafica1.XAxisLocation = 'origin';
            app.Grafica1.YAxisLocation = 'origin';
            app.Grafica2 = uiaxes(app.GRADEONEUIFigure);
            title(app.Grafica2, 'POSICION VS TIEMPO')
            xlabel(app.Grafica2, 'tiempo (t)')
            ylabel(app.Grafica2, 'u(t) - (m)')
            app.Grafica3 = uiaxes(app.GRADEONEUIFigure);
            title(app.Grafica3, 'VELOCIDAD VS TIEMPO')
            xlabel(app.Grafica3, 'tiempo (t)')
            ylabel(app.Grafica3, 'ů(t) - (m/s)')
            app.Grafica4 = uiaxes(app.GRADEONEUIFigure);
            title(app.Grafica4, 'FUERZA RESTITUTIVA VS TIEMPO')
            xlabel(app.Grafica4, 'tiempo (t)')
            ylabel(app.Grafica4, 'Fr (N)')
            app.Grafica5 = uiaxes(app.GRADEONEUIFigure);
            title(app.Grafica5, 'FUERZA DE AMORTIGUAMIENTO VS TIEMPO')
            xlabel(app.Grafica5, 'tiempo (t)')
            ylabel(app.Grafica5, 'Fd (N)')
            app.Grafica6 = uiaxes(app.GRADEONEUIFigure);
            title(app.Grafica6, 'FUERZA DE ACELERACION VS TIEMPO')
            xlabel(app.Grafica6, 'tiempo (t)')
            ylabel(app.Grafica6, 'a(t)- (m/s2)')
            app.INGRESODEDATOSPanel = uipanel(app.GRADEONEUIFigure);
            app.INGRESODEDATOSPanel.Title = 'INGRESO DE DATOS';
            app.MasaEdit = uieditfield(app.INGRESODEDATOSPanel, 'numeric');
            app.MasaEdit.ValueChangedFcn = createCallbackFcn(app, @MasaEditValueChanged, true);
            app.MASAMSliderLabel = uilabel(app.INGRESODEDATOSPanel);
            app.MASAMSliderLabel.Text = 'MASA = M';
            app.MasaSlider = uislider(app.INGRESODEDATOSPanel);
            app.MasaSlider.Limits = [0 1000];
            app.MasaSlider.ValueChangedFcn = createCallbackFcn(app, @MasaSliderValueChanged, true);
            app.RigidezEdit = uieditfield(app.INGRESODEDATOSPanel, 'numeric');
            app.RigidezEdit.ValueChangedFcn = createCallbackFcn(app, @RigidezEditValueChanged, true);
            app.PosiEdit = uieditfield(app.INGRESODEDATOSPanel, 'numeric');
            app.PosiEdit.ValueChangedFcn = createCallbackFcn(app, @PosiEditValueChanged, true);
            app.VelocEdit = uieditfield(app.INGRESODEDATOSPanel, 'numeric');
            app.VelocEdit.ValueChangedFcn = createCallbackFcn(app, @VelocEditValueChanged, true);
            app.RIGIDEZKSliderLabel = uilabel(app.INGRESODEDATOSPanel);
            app.RIGIDEZKSliderLabel.Text = 'RIGIDEZ = K';
            app.RigidezSlider = uislider(app.INGRESODEDATOSPanel);
            app.RigidezSlider.Limits = [0 1000];
            app.RigidezSlider.ValueChangedFcn = createCallbackFcn(app, @RigidezSliderValueChanged, true);
            app.POSICIONINICIALSliderLabel = uilabel(app.INGRESODEDATOSPanel);
            app.POSICIONINICIALSliderLabel.Text = {'POSICION'; 'INICIAL'; ''};
            app.PosiSlider = uislider(app.INGRESODEDATOSPanel);
            app.PosiSlider.ValueChangedFcn = createCallbackFcn(app, @PosiSliderValueChanged, true);
            app.VELOCIDADINICIALSliderLabel = uilabel(app.INGRESODEDATOSPanel);
            app.VELOCIDADINICIALSliderLabel.Text = {'VELOCIDAD'; 'INICIAL'; ''};
            app.VelocLSlider = uislider(app.INGRESODEDATOSPanel);
            app.VelocLSlider.Limits = [0 10];
            app.VelocLSlider.ValueChangedFcn = createCallbackFcn(app, @VelocLSliderValueChanged, true);
            app.DEAMORTCRITICOLabel = uilabel(app.INGRESODEDATOSPanel);
            app.DEAMORTCRITICOLabel.Text = {'% DE '; 'AMORT.'; 'CRITICO'};
            app.AmortSlider = uislider(app.INGRESODEDATOSPanel);
            app.AmortSlider.Limits = [0 0.99];
            app.AmortSlider.ValueChangedFcn = createCallbackFcn(app, @AmortSliderValueChanged, true);
            app.AmortEdit = uieditfield(app.INGRESODEDATOSPanel, 'numeric');
            app.AmortEdit.ValueChangedFcn = createCallbackFcn(app, @AmortEditValueChanged, true);
            app.RESULTADOSPanel = uipanel(app.GRADEONEUIFigure);
            app.RESULTADOSPanel.Title = 'RESULTADOS';
            app.CLabel = uilabel(app.RESULTADOSPanel);
            app.CLabel.Tooltip = {''};
            app.CLabel.Text = 'C =';
            app.CEditField = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.CEditField.Tooltip = {''};
            app.nLabel = uilabel(app.RESULTADOSPanel);
            app.nLabel.Tooltip = {''};
            app.nLabel.Text = 'ωn =';
            app.nEditField = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Editable = 'off';
            app.nEditField.Tooltip = {''};
            app.TnEditFieldLabel = uilabel(app.RESULTADOSPanel);
            app.TnEditFieldLabel.Tooltip = {''};
            app.TnEditFieldLabel.Text = 'Tn =';
            app.TnEditField = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.TnEditField.ValueChangedFcn = createCallbackFcn(app, @TnEditFieldValueChanged, true);
            app.TnEditField.Tooltip = {''};
            app.A1Label = uilabel(app.RESULTADOSPanel);
            app.A1Label.Tooltip = {''};
            app.A1Label.Text = 'A1 =';
            app.A1EditField = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.A1EditField.ValueChangedFcn = createCallbackFcn(app, @A1EditFieldValueChanged, true);
            app.A1EditField.Tooltip = {''};
            app.A2EditFieldLabel = uilabel(app.RESULTADOSPanel);
            app.A2EditFieldLabel.Tooltip = {''};
            app.A2EditFieldLabel.Text = 'A2 =';
            app.A2EditField = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.A2EditField.ValueChangedFcn = createCallbackFcn(app, @A2EditFieldValueChanged, true);
            app.A2EditField.Tooltip = {''};
            app.GRAFICASDEUNSISTEMADEUNGRADODELIBERTADLabel = uilabel(app.GRADEONEUIFigure);
            app.GRAFICASDEUNSISTEMADEUNGRADODELIBERTADLabel.Text = ' GRAFICAS DE UN SISTEMA DE UN GRADO DE LIBERTAD.';
            app.Label = uilabel(app.GRADEONEUIFigure);
            app.Label2 = uilabel(app.GRADEONEUIFigure);
            app.Label2.Text = 'Label2';
            app.ElaboradoporOLIVANJUANDEDIOSROJASLabel = uilabel(app.GRADEONEUIFigure);
            app.ElaboradoporOLIVANJUANDEDIOSROJASLabel.Text = 'Elaborado por: ÑOL IVAN JUAN DE DIOS ROJAS';
            % Show the figure after all components are created
            app.GRADEONEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GRADEONEUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GRADEONEUIFigure)
        end
    end
end